classdef appcov < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Label_17                       matlab.ui.control.Label
        TheappisbasedondataofCanadianpatientsLabel  matlab.ui.control.Label
        Label_16                       matlab.ui.control.Label
        Label_15                       matlab.ui.control.Label
        Label_14                       matlab.ui.control.Label
        Label_13                       matlab.ui.control.Label
        Label_12                       matlab.ui.control.Label
        Label_11                       matlab.ui.control.Label
        Label_10                       matlab.ui.control.Label
        Label_9                        matlab.ui.control.Label
        RcPEditField_4                 matlab.ui.control.NumericEditField
        RecEditField_4                 matlab.ui.control.NumericEditField
        NoHEditField_4                 matlab.ui.control.NumericEditField
        HosEditField_4                 matlab.ui.control.NumericEditField
        ICUEditField_4                 matlab.ui.control.NumericEditField
        RcPEditField_3                 matlab.ui.control.NumericEditField
        RecEditField_3                 matlab.ui.control.NumericEditField
        NoHEditField_3                 matlab.ui.control.NumericEditField
        HosEditField_3                 matlab.ui.control.NumericEditField
        ICUEditField_3                 matlab.ui.control.NumericEditField
        AverageLowHighLabel            matlab.ui.control.Label
        LikelihoodFactorsLabel         matlab.ui.control.Label
        PredictionModelLabel           matlab.ui.control.Label
        FromdataLabel                  matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        Label_7                        matlab.ui.control.Label
        Label_6                        matlab.ui.control.Label
        Label_5                        matlab.ui.control.Label
        RcPEditField_2                 matlab.ui.control.NumericEditField
        RecEditField_2                 matlab.ui.control.NumericEditField
        NoHEditField_2                 matlab.ui.control.NumericEditField
        HosEditField_2                 matlab.ui.control.NumericEditField
        ICUEditField_2                 matlab.ui.control.NumericEditField
        COVID19PrognosisLabel          matlab.ui.control.Label
        weeksLabel                     matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        Yes1No0Label                   matlab.ui.control.Label
        ResultLabel                    matlab.ui.control.Label
        SymptomsLabel                  matlab.ui.control.Label
        RecoveryPeriodEditField        matlab.ui.control.NumericEditField
        RecoveryPeriodEditFieldLabel   matlab.ui.control.Label
        RecoveredEditField             matlab.ui.control.NumericEditField
        RecoveredEditFieldLabel        matlab.ui.control.Label
        NothospitalizedEditField       matlab.ui.control.NumericEditField
        NothospitalizedEditFieldLabel  matlab.ui.control.Label
        NonICUEditField                matlab.ui.control.NumericEditField
        NonICUEditFieldLabel           matlab.ui.control.Label
        ICUEditField                   matlab.ui.control.NumericEditField
        ICUEditFieldLabel              matlab.ui.control.Label
        PatientEditField               matlab.ui.control.NumericEditField
        PatientEditFieldLabel          matlab.ui.control.Label
        SymptomEditField               matlab.ui.control.NumericEditField
        SymptomEditFieldLabel          matlab.ui.control.Label
        ValidateButton                 matlab.ui.control.Button
        ShortnessofbreathEditField     matlab.ui.control.NumericEditField
        ShortnessofbreathEditFieldLabel  matlab.ui.control.Label
        FeverEditField                 matlab.ui.control.NumericEditField
        FeverEditFieldLabel            matlab.ui.control.Label
        CoughEditField                 matlab.ui.control.NumericEditField
        CoughEditFieldLabel            matlab.ui.control.Label
        WeaknessEditField              matlab.ui.control.NumericEditField
        WeaknessEditFieldLabel         matlab.ui.control.Label
        PainEditField                  matlab.ui.control.NumericEditField
        PainEditFieldLabel             matlab.ui.control.Label
        RunnynoseEditField             matlab.ui.control.NumericEditField
        RunnynoseEditFieldLabel        matlab.ui.control.Label
        HeadacheEditField              matlab.ui.control.NumericEditField
        HeadacheEditFieldLabel         matlab.ui.control.Label
        SorethoratEditField            matlab.ui.control.NumericEditField
        SorethoratEditFieldLabel       matlab.ui.control.Label
        AgeEditField                   matlab.ui.control.NumericEditField
        AgeEditFieldLabel              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ValidateButton
        function ValidateButtonPushed(app, event)
            age = app.AgeEditField.Value;
            if (age >= 0) && (age <= 19)
                a = 1;
            end
            if (age >= 20) && (age <= 29)
                a = 2;
            end
            if (age >= 30) && (age <= 39)
                a = 3;
            end 
            if (age >= 40) && (age <= 49)
                a = 4;
            end   
            if (age >= 50) && (age <= 59)
                a = 5;
            end 
            if (age >= 60) && (age <= 69)
                a = 6;
            end
            if (age >= 70) && (age <= 79)
                a = 7;
            end
            if (age >= 80) 
                a = 8;
            end            
           
            n1 = app.SorethoratEditField.Value;
            n2 = app.HeadacheEditField.Value;
            n3 = app.RunnynoseEditField.Value;
            n4 = app.PainEditField.Value;
            n5 = app.WeaknessEditField.Value;
            n6 = app.CoughEditField.Value;
            n7 = app.FeverEditField.Value;
            n8 = app.ShortnessofbreathEditField.Value;
            symp = 128*n8+64*n7+32*n6+16*n5+8*n4+4*n3+2*n2+n1;
            app.SymptomEditField.Value = symp;
            np = 255*(a-1)+symp;
            app.PatientEditField.Value = np;
            cu1 = load('icu.txt');
            hs1 = load('hosp.txt');
            re1 = load('reco.txt');
            rp1 = load('arp.txt');
            
            cu2 = load('paicu.txt');
            hs2 = load('pahosp.txt');
            re2 = load('pareco.txt');
            rp2 = load('parp.txt');
            
            cu3 = load('plicu.txt');
            hs3 = load('plhosp.txt');
            re3 = load('plreco.txt');
            rp3 = load('plrp.txt');
            
            cu4 = load('puicu.txt');
            hs4 = load('puhosp.txt');
            re4 = load('pureco.txt');
            rp4 = load('purp.txt');
            
            app.ICUEditField.Value = cu1(np,1);
            app.NonICUEditField.Value = hs1(np,1);
            app.NothospitalizedEditField.Value = 100 - cu1(np,1)-hs1(np,1);
            app.RecoveredEditField.Value = re1(np,1);
            app.RecoveryPeriodEditField.Value = rp1(np,1);
            
            app.ICUEditField_2.Value = cu2(np,1);
            app.HosEditField_2.Value = hs2(np,1);
            app.NoHEditField_2.Value = 100 - cu2(np,1) - hs2(np,1);
            app.RecEditField_2.Value = re2(np,1);
            app.RcPEditField_2.Value = rp2(np,1);
            
            app.ICUEditField_3.Value = cu3(np,1);
            app.HosEditField_3.Value = hs3(np,1);
            app.NoHEditField_3.Value = 100 - cu4(np,1) - hs4(np,1);
            app.RecEditField_3.Value = re3(np,1);
            app.RcPEditField_3.Value = rp3(np,1);
            
            app.ICUEditField_4.Value = cu4(np,1);
            app.HosEditField_4.Value = hs4(np,1);
            app.NoHEditField_4.Value = 100 - cu3(np,1) - hs3(np,1);
            app.RecEditField_4.Value = re4(np,1);
            app.RcPEditField_4.Value = rp4(np,1);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AgeEditFieldLabel = uilabel(app.UIFigure);
            app.AgeEditFieldLabel.Text = 'Age';
            app.AgeEditField = uieditfield(app.UIFigure, 'numeric');
            app.AgeEditField.Value = 68;
            app.SorethoratEditFieldLabel = uilabel(app.UIFigure);
            app.SorethoratEditFieldLabel.Text = 'Sore thorat';
            app.SorethoratEditField = uieditfield(app.UIFigure, 'numeric');
            app.SorethoratEditField.Value = 1;
            app.HeadacheEditFieldLabel = uilabel(app.UIFigure);
            app.HeadacheEditFieldLabel.Text = 'Headache';
            app.HeadacheEditField = uieditfield(app.UIFigure, 'numeric');
            app.RunnynoseEditFieldLabel = uilabel(app.UIFigure);
            app.RunnynoseEditFieldLabel.Text = 'Runny nose';
            app.RunnynoseEditField = uieditfield(app.UIFigure, 'numeric');
            app.PainEditFieldLabel = uilabel(app.UIFigure);
            app.PainEditFieldLabel.Text = 'Pain';
            app.PainEditField = uieditfield(app.UIFigure, 'numeric');
            app.PainEditField.Value = 1;
            app.WeaknessEditFieldLabel = uilabel(app.UIFigure);
            app.WeaknessEditFieldLabel.Text = 'Weakness';
            app.WeaknessEditField = uieditfield(app.UIFigure, 'numeric');
            app.WeaknessEditField.Value = 1;
            app.CoughEditFieldLabel = uilabel(app.UIFigure);
            app.CoughEditFieldLabel.Text = 'Cough';
            app.CoughEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoughEditField.Value = 1;
            app.FeverEditFieldLabel = uilabel(app.UIFigure);
            app.FeverEditFieldLabel.Text = 'Fever';
            app.FeverEditField = uieditfield(app.UIFigure, 'numeric');
            app.FeverEditField.Value = 1;
            app.ShortnessofbreathEditFieldLabel = uilabel(app.UIFigure);
            app.ShortnessofbreathEditFieldLabel.Text = 'Shortness of breath';
            app.ShortnessofbreathEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShortnessofbreathEditField.Value = 1;
            app.ValidateButton = uibutton(app.UIFigure, 'push');
            app.ValidateButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateButtonPushed, true);
            app.ValidateButton.Text = 'Validate';
            app.SymptomEditFieldLabel = uilabel(app.UIFigure);
            app.SymptomEditFieldLabel.Text = 'Symptom #';
            app.SymptomEditField = uieditfield(app.UIFigure, 'numeric');
            app.PatientEditFieldLabel = uilabel(app.UIFigure);
            app.PatientEditFieldLabel.Text = 'Patient #';
            app.PatientEditField = uieditfield(app.UIFigure, 'numeric');
            app.ICUEditFieldLabel = uilabel(app.UIFigure);
            app.ICUEditFieldLabel.Text = 'ICU';
            app.ICUEditField = uieditfield(app.UIFigure, 'numeric');
            app.NonICUEditFieldLabel = uilabel(app.UIFigure);
            app.NonICUEditFieldLabel.Text = 'Non ICU';
            app.NonICUEditField = uieditfield(app.UIFigure, 'numeric');
            app.NothospitalizedEditFieldLabel = uilabel(app.UIFigure);
            app.NothospitalizedEditFieldLabel.Text = 'Not hospitalized';
            app.NothospitalizedEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecoveredEditFieldLabel = uilabel(app.UIFigure);
            app.RecoveredEditFieldLabel.Text = 'Recovered';
            app.RecoveredEditField = uieditfield(app.UIFigure, 'numeric');
            app.RecoveryPeriodEditFieldLabel = uilabel(app.UIFigure);
            app.RecoveryPeriodEditFieldLabel.Text = 'Recovery Period';
            app.RecoveryPeriodEditField = uieditfield(app.UIFigure, 'numeric');
            app.SymptomsLabel = uilabel(app.UIFigure);
            app.SymptomsLabel.Text = '                 Symptoms';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = '                         Result';
            app.Yes1No0Label = uilabel(app.UIFigure);
            app.Yes1No0Label.Text = 'Yes = 1 , No = 0';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '%';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '%';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '%';
            app.weeksLabel = uilabel(app.UIFigure);
            app.weeksLabel.Text = 'week(s)';
            app.COVID19PrognosisLabel = uilabel(app.UIFigure);
            app.COVID19PrognosisLabel.Text = 'COVID-19 Prognosis';
            app.ICUEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.HosEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NoHEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RecEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RcPEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '%';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '%';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '%';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '%';
            app.FromdataLabel = uilabel(app.UIFigure);
            app.FromdataLabel.Text = 'From data';
            app.PredictionModelLabel = uilabel(app.UIFigure);
            app.PredictionModelLabel.Text = 'Prediction Model';
            app.LikelihoodFactorsLabel = uilabel(app.UIFigure);
            app.LikelihoodFactorsLabel.Text = 'Likelihood Factors';
            app.AverageLowHighLabel = uilabel(app.UIFigure);
            app.AverageLowHighLabel.Text = 'Average                 Low                    High';
            app.ICUEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.HosEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.NoHEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RecEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.RcPEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.ICUEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.HosEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.NoHEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.RecEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.RcPEditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '%';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '%';
            app.Label_11 = uilabel(app.UIFigure);
            app.Label_11.Text = '%';
            app.Label_12 = uilabel(app.UIFigure);
            app.Label_12.Text = '%';
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = '%';
            app.Label_14 = uilabel(app.UIFigure);
            app.Label_14.Text = '%';
            app.Label_15 = uilabel(app.UIFigure);
            app.Label_15.Text = '%';
            app.Label_16 = uilabel(app.UIFigure);
            app.Label_16.Text = '%';
            app.TheappisbasedondataofCanadianpatientsLabel = uilabel(app.UIFigure);
            app.TheappisbasedondataofCanadianpatientsLabel.Text = 'The app is based on data of Canadian patients.';
            app.Label_17 = uilabel(app.UIFigure);
            app.Label_17.Text = '______________________________________________________';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appcov

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end